classdef LAB_1_SERGIO_CARLOS_SEBASTIAN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        EscojalatransformacinarealizarydigitesusvaloresLabel  matlab.ui.control.Label
        Mensaje1Label            matlab.ui.control.Label
        SELECCIONEELTIPODESEALPARAPODERINICIARELPROGRAMALabel  matlab.ui.control.Label
        Button2                  matlab.ui.control.Button
        Button                   matlab.ui.control.Button
        Esce_yEditField          matlab.ui.control.NumericEditField
        Valor3Label              matlab.ui.control.Label
        Esce_tEditField          matlab.ui.control.NumericEditField
        Valor2Label              matlab.ui.control.Label
        Despe_tEditField         matlab.ui.control.NumericEditField
        Valor3EditFieldLabel     matlab.ui.control.Label
        Valor1Label              matlab.ui.control.Label
        EditField3               matlab.ui.control.NumericEditField
        EditField2               matlab.ui.control.NumericEditField
        Valor2EditFieldLabel     matlab.ui.control.Label
        EditField                matlab.ui.control.NumericEditField
        Valor1EditFieldLabel     matlab.ui.control.Label
        Escalamiento_t           matlab.ui.control.DropDown
        TipodeescalamientoLabel  matlab.ui.control.Label
        Escalamiento_y           matlab.ui.control.DropDown
        Seleccion4Label          matlab.ui.control.Label
        Desplazamiento_t         matlab.ui.control.DropDown
        Despd_tLabel             matlab.ui.control.Label
        DropDown                 matlab.ui.control.DropDown
        TiipodesealLabel         matlab.ui.control.Label
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        y % Señal creada
        t % acotado del eje t
        T % Periodo
        a % Amplitud (señales periodicas)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Button, DropDown
        function ButtonPushed(app, event)

            s = app.DropDown.Value;
            if strcmp(s, "-")
                app.SELECCIONEELTIPODESEALPARAPODERINICIARELPROGRAMALabel.Visible = "on";

            elseif strcmp(s,"Senoidal")
                w=app.EditField.Value;
                app.a=app.EditField2.Value;
                app.T=2*3.1416/w;
                app.t = -4*pi/(w):1/(w*2):4*pi/(w);
                app.y = app.a*sin(w*app.t);
                plot(app.UIAxes,app.t,app.y)
                app.UIAxes.XGrid = "on";
                app.UIAxes.YGrid = "on";
                app.UIAxes.Title.String = "Gráfica senoidal";
                app.UIAxes.XLabel.String = "Frecuencia (w)";
                app.UIAxes.YLabel.String = "Amplitud (A)";
                app.EditField.Visible = "on";
                app.EditField2.Visible = "on";
                app.EditField3.Visible = "off";
                app.Valor3EditFieldLabel.Visible = "off";
                app.Valor1EditFieldLabel.Text = "Frecuencia (F)";
                app.Valor2EditFieldLabel.Text = "Amplitud (A)";
                app.Mensaje1Label.Text = "Digite los valores de F y A:";
                app.SELECCIONEELTIPODESEALPARAPODERINICIARELPROGRAMALabel.Visible = "off";

            elseif strcmp(s,"Pulso")
                app.a= app.EditField.Value;
                l= app.EditField2.Value;
                app.t=-l:1/1000:l;
                app.y=rectpuls(app.t,l);
                app.y=app.a*app.y;
                plot(app.UIAxes,app.t,app.y)
                app.UIAxes.XGrid = "on";
                app.UIAxes.YGrid = "on";
                app.UIAxes.Title.String = "Gráfica Pulso";
                app.UIAxes.XLabel.String = "Ancho (L)";
                app.UIAxes.YLabel.String = "Amplitud (A)";
                app.EditField.Visible = "on";
                app.EditField2.Visible = "on";
                app.EditField3.Visible = "off";
                app.Valor3EditFieldLabel.Visible = "off";
                app.Valor1EditFieldLabel.Text = "Amplitud (A)";
                app.Valor2EditFieldLabel.Text = "Ancho (L)";
                app.Mensaje1Label.Text = "Digite los valores de A y L:";
                app.SELECCIONEELTIPODESEALPARAPODERINICIARELPROGRAMALabel.Visible = "off";

            elseif strcmp(s,"Cuadrática")
                app.a= app.EditField.Value;
                b= app.EditField2.Value;
                c= app.EditField3.Value;
                app.t=-2*pi:1/1000:2*pi;
                app.y = app.a*app.t.^2+b*app.t+c;
                plot(app.UIAxes,app.t,app.y)
                app.UIAxes.XGrid = "on";
                app.UIAxes.YGrid = "on";
                app.UIAxes.Title.String = "Gráfica Cuadrática";
                app.UIAxes.XLabel.String = "X";
                app.UIAxes.YLabel.String = "Y";
                app.EditField.Visible = "on";
                app.EditField2.Visible = "on";
                app.EditField3.Visible = "on";
                app.Valor3EditFieldLabel.Visible = "on";
                app.Valor1EditFieldLabel.Text = "a";
                app.Valor2EditFieldLabel.Text = "b";
                app.Valor3EditFieldLabel.Text = "c";
                app.Mensaje1Label.Text = "Digite los valores de a, b, y c:";
                app.SELECCIONEELTIPODESEALPARAPODERINICIARELPROGRAMALabel.Visible = "off";

            elseif strcmp(s,"Exponencial")
                app.a= app.EditField.Value;
                b= app.EditField2.Value;
                app.t=-2*pi/(b+1):1/1000:2*pi/(b+1);
                app.y=app.a*exp(-b*app.t);
                plot(app.UIAxes,app.t,app.y)
                app.UIAxes.XGrid = "on";
                app.UIAxes.YGrid = "on";
                app.UIAxes.Title.String = "Gráfica Exponencial";
                app.UIAxes.XLabel.String = "X";
                app.UIAxes.YLabel.String = "Y";
                app.EditField.Visible = "on";
                app.EditField2.Visible = "on";
                app.EditField3.Visible = "off";
                app.Valor3EditFieldLabel.Visible = "off";
                app.Valor1EditFieldLabel.Text = "a";
                app.Valor2EditFieldLabel.Text = "b";
                app.Mensaje1Label.Text = "Digite los valores de a y b:";
                app.SELECCIONEELTIPODESEALPARAPODERINICIARELPROGRAMALabel.Visible = "off";

            elseif strcmp(s,"Lineal")
                m= app.EditField.Value;
                b= app.EditField2.Value;
                app.t=-2*pi:1/1000:2*pi;
                app.y=m*app.t+b;
                plot(app.UIAxes,app.t,app.y)
                app.UIAxes.XGrid = "on";
                app.UIAxes.YGrid = "on";
                app.UIAxes.Title.String = "Gráfica Lineal";
                app.UIAxes.XLabel.String = "X";
                app.UIAxes.YLabel.String = "Y";
                app.EditField.Visible = "on";
                app.EditField2.Visible = "on";
                app.EditField3.Visible = "off";
                app.Valor3EditFieldLabel.Visible = "off";
                app.Valor1EditFieldLabel.Text = "Pendiente (m)";
                app.Valor2EditFieldLabel.Text = "b";
                app.Mensaje1Label.Text = "Digite los valores de m y b:";
                app.SELECCIONEELTIPODESEALPARAPODERINICIARELPROGRAMALabel.Visible = "off";

            elseif strcmp(s,"Triangular")
                app.a= app.EditField.Value;
                w= app.EditField2.Value;
                k= app.EditField3.Value;
                f=w/2*pi;
                app.T=1/f;
                app.t=-2*pi/(w+1):1/1000:2*pi/(w+1);
                app.y=sawtooth(w*app.t,k);
                app.y=app.a*app.y;
                plot(app.UIAxes,app.t,app.y)
                app.UIAxes.XGrid = "on";
                app.UIAxes.YGrid = "on";
                app.UIAxes.Title.String = "Gráfica Triangular";
                app.UIAxes.XLabel.String = "Frecuencia (w)";
                app.UIAxes.YLabel.String = "Amplitud (A)";
                app.EditField.Visible = "on";
                app.EditField2.Visible = "on";
                app.EditField3.Visible = "on";
                app.Valor3EditFieldLabel.Visible = "on";
                app.Valor1EditFieldLabel.Text = "Amplitud (A)";
                app.Valor2EditFieldLabel.Text = "Frecuencia (w)";
                app.Valor3EditFieldLabel.Text = "k";
                app.Mensaje1Label.Text = "Digite los valores de A, w, y k (¡PARA k SOLO ENTRE 0 y 1!):";
                app.SELECCIONEELTIPODESEALPARAPODERINICIARELPROGRAMALabel.Visible = "off";

            elseif strcmp(s,"Cuadrada")
                app.a= app.EditField.Value;
                l= app.EditField2.Value;
                p= app.EditField3.Value;
                app.t= -l*4*pi:1/1000:l*4*pi;
                app.y=square((1/l)*app.t,p);
                app.y=app.y*app.a;
                plot(app.UIAxes,app.t,app.y)
                app.UIAxes.XGrid = "on";
                app.UIAxes.YGrid = "on";
                app.UIAxes.Title.String = "Gráfica Cuadrada";
                app.UIAxes.XLabel.String = "Ancho (L)";
                app.UIAxes.YLabel.String = "Amplitud (A)";
                app.EditField.Visible = "on";
                app.EditField2.Visible = "on";
                app.EditField3.Visible = "on";
                app.Valor3EditFieldLabel.Visible = "on";
                app.Valor1EditFieldLabel.Text = "Amplitud (A)";
                app.Valor2EditFieldLabel.Text = "Ancho (L)";
                app.Valor3EditFieldLabel.Text = "p";
                app.Mensaje1Label.Text = "Digite los valores de A, L, y p:";
                app.SELECCIONEELTIPODESEALPARAPODERINICIARELPROGRAMALabel.Visible = "off";

            elseif strcmp(s,"Secuencia de impulsos")
                w = app.EditField.Value;
                app.t = 0:1/1e3:w;
                d = (0:2:w)';
                x = @rectpuls;
                app.y = pulstran(app.t,d,x);
                plot(app.UIAxes,app.t,app.y)
                hold off
                app.UIAxes.XGrid = "on";
                app.UIAxes.YGrid = "on";
                app.UIAxes.Title.String = "Gráfica Secuencia de impulso";
                app.UIAxes.XLabel.String = "X";
                app.UIAxes.YLabel.String = "Y";
                app.EditField.Visible = "on";
                app.EditField2.Visible = "off";
                app.EditField3.Visible = "off";
                app.Valor3EditFieldLabel.Visible = "off";
                app.Valor2EditFieldLabel.Visible = "off";
                app.Valor1EditFieldLabel.Text = "A";
                app.Mensaje1Label.Text = "Digite el valor de A";
                app.SELECCIONEELTIPODESEALPARAPODERINICIARELPROGRAMALabel.Visible = "off";
            end
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            app.UIAxes2.XGrid = "on";
            app.UIAxes2.YGrid = "on";
            ax = app.UIAxes2;
            %
            %
            %
            % Desplazamiento en el tiempo (t)
            %
            %
            %
            d1= app.Despe_tEditField.Value;
            % d1= cantidad de unidades a desplazar
            s2= app.Desplazamiento_t.Value;
            % s2= Dropdown 2
            
            if strcmp(s2,"Izquierda")
              if d1 ~= 0
                 ax.XLim = [(-d1-2*app.T) (2*app.T)];
                 for c = 0:1:d1
                 plot(app.UIAxes2,app.t-c,app.y)
                 pause(0.5)
                 end
                 app.t=app.t-d1;
                 d1=-d1;
                 
              end
              if d1 == 0
                  ax.XLim = [(-2*app.T) (2*app.T)];
              end
            end
            if strcmp(s2,"Derecha")
              if d1 ~= 0
                ax.XLim = [(-2*app.T) ((d1)+2*app.T)];
                for c = 0:1:d1
                plot(app.UIAxes2,app.t+c,app.y)
                pause(0.5)
                end
                app.t=app.t+d1;
                
              end
              if d1 == 0
                 ax.XLim = [-2*app.T 2*app.T];
              end
            end
            %
            %
            %
            % Escalamiento en el tiempo (t)
            %
            %
            %
            e1= app.Esce_tEditField.Value;
            % e1= cantidad de unidades a escalar
            s3= app.Escalamiento_t.Value;
            % s3= Dropdown 3
            if strcmp(s3,"Expandir")
               if e1 ~= 0
                ax.XLim = [((((-2*app.T)+d1)*e1)) (2*app.T+d1)];
                for c = 1:1:e1
                plot(app.UIAxes2,app.t*c,app.y)
                pause(0.5) 
                end
                app.t=app.t*e1;
               end
               if e1 == 0
               ax.XLim =[-2*app.T+d1 2*app.T+d1];
               end

            end
            if strcmp(s3,"Comprimir")
               if e1 ~= 0
                 ax.XLim = [(-(2*app.T)+d1) ((2*app.T)+d1)];
                for c=1:1:e1
                plot(app.UIAxes2,app.t/c,app.y)
                pause(0.5)
                end
                app.t=app.t*e1;
               end
               if e1 == 0
                  ax.XLim = [-2*app.T 2*app.T];
               end
            end
            %
            %
            %
            % Escalamiento en amplitud (A)
            %
            %
            %
            e2= app.Esce_yEditField.Value;
            % e2= cantidad de unidades a escalar
            s5= app.Escalamiento_y.Value;
            % s5= Dropdown 5
            if strcmp(s5,"Amplificar")
                if e2 ~= 0
                 ax.YLim = [(-app.a*e2) (app.a*e2)];
                 %ax.YLim = [-app.a+e2 app.a+e2];
                 for c = 1:1:e2
                 plot (app.UIAxes2,app.t,app.y*c)
                 pause(0.5)
                 end
                end
                if e2 == 0
                  ax.YLim = [-app.a app.a];
                end
            end
            if strcmp(s5,"Atenuar")
                if e2 ~= 0
                ax.YLim = [-app.a app.a];
                 for c = 1:1:e2
                 plot (app.UIAxes2,app.t,app.y/c)
                 pause(0.5)
                 end
                end
                if e2 == 0
                ax.YLim = [-app.a app.a];
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [-10 10];
            app.UIAxes2.YLim = [-10 10];
            app.TiipodesealLabel = uilabel(app.UIFigure);
            app.TiipodesealLabel.Text = 'Tipo de señal';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'-', 'Senoidal', 'Pulso', 'Cuadrática', 'Exponencial', 'Lineal', 'Triangular', 'Cuadrada', 'Secuencia de impulsos'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DropDown.Value = '-';
            app.Despd_tLabel = uilabel(app.UIFigure);
            app.Despd_tLabel.Text = 'Tipo de desplaza.';
            app.Desplazamiento_t = uidropdown(app.UIFigure);
            app.Desplazamiento_t.Items = {'Izquierda', 'Derecha'};
            app.Desplazamiento_t.Value = 'Izquierda';
            app.Seleccion4Label = uilabel(app.UIFigure);
            app.Seleccion4Label.Text = 'Tipo de escala (y)';
            app.Escalamiento_y = uidropdown(app.UIFigure);
            app.Escalamiento_y.Items = {'Amplificar', 'Atenuar'};
            app.Escalamiento_y.Value = 'Amplificar';
            app.TipodeescalamientoLabel = uilabel(app.UIFigure);
            app.TipodeescalamientoLabel.Text = 'Tipo de  escala (x)';
            app.Escalamiento_t = uidropdown(app.UIFigure);
            app.Escalamiento_t.Items = {'Expandir', 'Comprimir'};
            app.Escalamiento_t.Value = 'Expandir';
            app.Valor1EditFieldLabel = uilabel(app.UIFigure);
            app.Valor1EditFieldLabel.Text = 'Valor1';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.Valor2EditFieldLabel = uilabel(app.UIFigure);
            app.Valor2EditFieldLabel.Text = 'Valor2';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.Valor1Label = uilabel(app.UIFigure);
            app.Valor1Label.Text = 'Valor 1';
            app.Valor3EditFieldLabel = uilabel(app.UIFigure);
            app.Valor3EditFieldLabel.Text = 'Valor3';
            app.Despe_tEditField = uieditfield(app.UIFigure, 'numeric');
            app.Valor2Label = uilabel(app.UIFigure);
            app.Valor2Label.Text = 'Valor 2';
            app.Esce_tEditField = uieditfield(app.UIFigure, 'numeric');
            app.Valor3Label = uilabel(app.UIFigure);
            app.Valor3Label.Text = 'Valor 3';
            app.Esce_yEditField = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = 'Button2';
            app.SELECCIONEELTIPODESEALPARAPODERINICIARELPROGRAMALabel = uilabel(app.UIFigure);
            app.SELECCIONEELTIPODESEALPARAPODERINICIARELPROGRAMALabel.Text = '¡¡ SELECCIONE EL TIPO DE SEÑAL PARA PODER INICIAR EL PROGRAMA !!';
            app.Mensaje1Label = uilabel(app.UIFigure);
            app.Mensaje1Label.Text = 'Mensaje1';
            app.EscojalatransformacinarealizarydigitesusvaloresLabel = uilabel(app.UIFigure);
            app.EscojalatransformacinarealizarydigitesusvaloresLabel.Text = 'Escoja la transformación a realizar y digite sus valores:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LAB_1_SERGIO_CARLOS_SEBASTIAN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end